classdef Slidip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlidipUIFigure              matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        NextButton                  matlab.ui.control.Button
        BackButton                  matlab.ui.control.Button
        SobelthresholdSpinnerLabel  matlab.ui.control.Label
        SobelthresholdSpinner       matlab.ui.control.Spinner
        AngularstepSpinnerLabel     matlab.ui.control.Label
        AngularstepSpinner          matlab.ui.control.Spinner
        DistancestepSpinnerLabel    matlab.ui.control.Label
        DistancestepSpinner         matlab.ui.control.Spinner
        LoadImageButton             matlab.ui.control.Button
        HoughthresholdSpinnerLabel  matlab.ui.control.Label
        HoughthresholdSpinner       matlab.ui.control.Spinner
        HoughmodeDropDownLabel      matlab.ui.control.Label
        HoughmodeDropDown           matlab.ui.control.DropDown
        ColorweightLabel            matlab.ui.control.Label
        RSpinner                    matlab.ui.control.Spinner
        GSpinner                    matlab.ui.control.Spinner
        BSpinner                    matlab.ui.control.Spinner
        ResetplotButton             matlab.ui.control.Button
        AnglularwindowSpinnerLabel  matlab.ui.control.Label
        AnglularwindowSpinner       matlab.ui.control.Spinner
        DistancewindowSpinnerLabel  matlab.ui.control.Label
        DistancewindowSpinner       matlab.ui.control.Spinner
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        State = 0; % This variable holds the current state
        MaxState = 6; % This variable represent the highest possible state
        RawImage = 0; % This variable stores the unprocessed image
        BWImage = 0;
        SobelImage = 0;
        HoughImage = 0;
        HoughThreshold = 0;
        FinishedImage = 0;
        CurrentPlot = 0;
        VAlpha; % Vektor containing the discrete angulare steps used in the houghtrafo
        VDistance; % Vektor containing the descrete distance steps used in the houghtrafo
        LineTensor; % Description
        alphaticks = 0:20:(pi*100) % Description
    end
    
    methods (Access = private)
        % sloppy pseudo state machine
        function executeStateUpdate(app)
            switch app.State
                case 0 % no image available
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.UIAxes.Title.String = 'No Image available';
                    app.NextButton.Enable = false;
                    app.BackButton.Enable = false;
                    app.RSpinner.Enable = false;
                    app.GSpinner.Enable = false;
                    app.BSpinner.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    app.AnglularwindowSpinner.Enable = false;
                    app.DistancewindowSpinner.Enable = false;
                    imagesc(app.UIAxes, 0);
                    app.CurrentPlot = 0;
                    disp(app.State)
                    
                case 1 % image loaded
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.UIAxes.Title.String = 'Image Preview';
                    app.UIAxes.XLim = [0 size(app.RawImage,2)];
                    app.UIAxes.YLim = [0 size(app.RawImage,1)];
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = false;
                    app.RSpinner.Enable = false;
                    app.GSpinner.Enable = false;
                    app.BSpinner.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    app.AnglularwindowSpinner.Enable = false;
                    app.DistancewindowSpinner.Enable = false;
                    app.FormatAxis(app.RawImage, app.UIAxes);
                    app.CurrentPlot = app.RawImage;
                    imagesc(app.UIAxes, app.RawImage);
                    
                case 2 % RGB2Bw
                    colormap(app.UIAxes, "gray");
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.UIAxes.Title.String = 'Black and White';
                    app.ApplyRGB2BW();
                    app.UIAxes.XLim = [0 size(app.RawImage,2)];
                    app.UIAxes.YLim = [0 size(app.RawImage,1)];
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = false;
                    app.RSpinner.Enable = true;
                    app.GSpinner.Enable = true;
                    app.BSpinner.Enable = true;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    app.AnglularwindowSpinner.Enable = false;
                    app.DistancewindowSpinner.Enable = false;
                    app.FormatAxis(app.BWImage, app.UIAxes);
                    imagesc(app.UIAxes, app.BWImage);
                    app.CurrentPlot = app.BWImage;
                    
                case 3 % sobel
                    colormap(app.UIAxes, "gray");
                    app.UIAxes.Title.String = 'Sobel filter';
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.UIAxes.XLim = [0 size(app.RawImage,2)];
                    app.UIAxes.YLim = [0 size(app.RawImage,1)];
                    app.UIAxes.YTickMode = 'auto';                    
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.RSpinner.Enable = false;
                    app.GSpinner.Enable = false;
                    app.BSpinner.Enable = false;
                    app.SobelthresholdSpinner.Enable = true;
                    app.AngularstepSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    app.AnglularwindowSpinner.Enable = false;
                    app.DistancewindowSpinner.Enable = false;
                    app.ApplySobelOperator()
                    app.FormatAxis(app.SobelImage, app.UIAxes);
                    imagesc(app.UIAxes, app.SobelImage);
                    app.CurrentPlot = app.SobelImage;
                    drawnow;
                    
                case 4 % hough trafo
                    app.UIAxes.Title.String = 'Hough transformation... Processing';
                    app.UIAxes.YAxis.Visible = 'on';
                    app.UIAxes.XAxis.Visible = 'on';                  
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.RSpinner.Enable = false;
                    app.GSpinner.Enable = false;
                    app.BSpinner.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = true;
                    app.DistancestepSpinner.Enable = true;
                    app.HoughthresholdSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = true;
                    app.AnglularwindowSpinner.Enable = false;                    
                    drawnow;
                    app.ApplyHoughTransformation();
                    app.FormatHoughTrafoAxes();                      
                    imagesc(app.UIAxes, app.HoughImage);
                    app.CurrentPlot = app.HoughImage;
                    app.UIAxes.Title.String = 'Hough transformation';     
                    
                    app.AnglularwindowSpinner.Step = app.AngularstepSpinner.Value;
                    app.AnglularwindowSpinner.Limits = [-app.AngularstepSpinner.Value Inf];
                    app.AnglularwindowSpinner.Value = -app.AngularstepSpinner.Value;
                    app.DistancewindowSpinner.Step = app.DistancestepSpinner.Value;
                    app.DistancewindowSpinner.Limits = [-app.DistancestepSpinner.Value Inf];
                    app.DistancewindowSpinner.Value = -app.DistancestepSpinner.Value;
                
                case 5 % hough threshold
                    app.UIAxes.Title.String = 'Hough Treshold';
                    app.UIAxes.YAxis.Visible = 'on';
                    app.UIAxes.XAxis.Visible = 'on';
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.RSpinner.Enable = false;
                    app.GSpinner.Enable = false;
                    app.BSpinner.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = true;
                    app.HoughmodeDropDown.Enable = false;
                    app.AnglularwindowSpinner.Enable = false;
                    app.DistancewindowSpinner.Enable = false;
                    app.ApplyThreshold()
                    app.FormatHoughTrafoAxes();
                    imagesc(app.UIAxes, app.HoughThreshold);
                    app.CurrentPlot = app.HoughThreshold;
                    app.UIAxes.Title.String = 'Hough Treshold';
                    
                case 6 % reverse hogh trafo                    
                    app.UIAxes.Title.String = 'Reconstructing image... Processing';
                    app.UIAxes.YAxis.Visible = 'off';
                    app.UIAxes.XAxis.Visible = 'off';
                    app.FormatAxis(app.RawImage, app.UIAxes);
                    app.UIAxes.YTickMode = 'auto';
                    app.UIAxes.YTickLabel = 'auto';
                    app.NextButton.Enable = false;
                    app.BackButton.Enable = true;
                    app.RSpinner.Enable = false;
                    app.GSpinner.Enable = false;
                    app.BSpinner.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularstepSpinner.Enable = false;
                    app.HoughthresholdSpinner.Enable = false;
                    app.DistancestepSpinner.Enable = false;
                    app.HoughmodeDropDown.Enable = false;
                    app.AnglularwindowSpinner.Enable = true;
                    app.DistancewindowSpinner.Enable = true;
                    drawnow;
                    app.LineTensor = ReverseHoughtransformation( ...
                        app.HoughThreshold, ...                      
                        size(app.RawImage), ...
                        app.AngularstepSpinner.Value, ...
                        app.DistancestepSpinner.Value, ...
                        app.AnglularwindowSpinner.Value,...
                        app.DistancewindowSpinner.Value);
                    imagesc(app.UIAxes, app.RawImage);
                    hold(app.UIAxes, "on")
                    for k = 1 : size(app.LineTensor, 1)
                        minpoint = app.LineTensor(k, :, 1);
                        maxpoint = app.LineTensor(k, :, 2);
                        plot(app.UIAxes, [minpoint(1) maxpoint(1)], [minpoint(2), maxpoint(2)])
                    end
                    hold(app.UIAxes, "off")
                    app.CurrentPlot = app.RawImage;
                    app.UIAxes.Title.String = 'Reconstructed image';
                    
                otherwise
                    disp("unknown state")
            end
        end
        
        function picture = LoadImage(app)
            picture = 0;
            [file, path, ~] = uigetfile({'*.png', 'PNG'; '*.jpg', 'JPEG'}, "please choose a picture");
            filepath = strcat(path, file);
            if filepath ~= ""
                picture = imread(filepath);
            end
            figure(app.SlidipUIFigure)
        end
        
        function ApplyRGB2BW(app)
            r = app.RSpinner.Value/100;
            g = app.GSpinner.Value/100;
            b = app.BSpinner.Value/100;
            app.BWImage = ColoredImageToGrayScale(app.RawImage, [r,g,b]);
        end
        
        function ApplySobelOperator(app)
            value = app.SobelthresholdSpinner.Value;
            app.SobelImage = Threshold(SobelOperator(app.BWImage), value);
        end
        
        function ApplyHoughTransformation(app)
            pistep = app.AngularstepSpinner.Value;
            dstep = app.DistancestepSpinner.Value;
            if app.HoughmodeDropDown.Value == "For loop"
                [app.HoughImage, app.VDistance, app.VAlpha] = ForLoopHoughTransformation(app.SobelImage, pistep, dstep);
            elseif app.HoughmodeDropDown.Value == "Tensor mode"
                try
                    [app.HoughImage, app.VDistance, app.VAlpha] = TensorModeHoughTransformation(app.SobelImage, pistep, dstep);
                catch ME
                    if (strcmp(ME.identifier,'MATLAB:array:SizeLimitExceeded'))
                        message = "SizeLimitExceeded: Matlabs current configuration doesn't allow tensors that. Tipp: This mode is best when handling small pictures or rough settings. Consider using another mode";
                        uialert(app.SlidipUIFigure, message, "An error occurred")
                    end
                end
            end
        end
        
        function ApplyThreshold(app)
            value = app.HoughthresholdSpinner.Value;
            app.HoughThreshold = Threshold(app.HoughImage, value);
        end
        
        function FormatAxis(~,Image, UIaxes)            
            UIaxes.XLim = [0.5 size(Image,2)+0.5];           
            UIaxes.YLim = [0.5 size(Image,1)+0.5];
        end
        
        
        function FormatHoughTrafoAxes(app)
            colormap(app.UIAxes, "parula");
            app.FormatAxis(app.HoughImage, app.UIAxes);
            app.UIAxes.XLabel.String = 'Distance d from origin';
            app.UIAxes.XTickMode = 'manual';  
            app.UIAxes.XTickLabelMode = 'manual';
            app.UIAxes.XTick = linspace(1, size(app.HoughImage,2), 3);
            app.UIAxes.XTickLabel = ({app.VDistance(1) 0 app.VDistance(end)});
            app.UIAxes.YLabel.String = 'Angle from left border counterclockwise in rad';
            app.UIAxes.YTickMode = 'manual';  
            app.UIAxes.YTickLabelMode = 'manual';
            app.UIAxes.YTick = linspace(1, size(app.HoughImage, 1), 5);
            app.UIAxes.YTickLabel = ({'0','\pi/4','\pi/2','3\pi/4','\pi'});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStatup(app)
            app.executeStateUpdate()
        end

        % Button pushed function: NextButton
        function BNextPushed(app, event)
            if app.State < app.MaxState
                app.State = app.State + 1;
            end
            app.executeStateUpdate();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SlidipUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {581, 581};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {367, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: BackButton
        function BBackPushed(app, event)
            if app.State > 0
                app.State = app.State - 1;
            end
            app.executeStateUpdate();
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           app.RawImage = app.LoadImage();
           app.State = 1;
           if app.RawImage == 0
               app.State = 0;
           end
           app.executeStateUpdate();
        end

        % Value changed function: SobelthresholdSpinner
        function SobelThresholdChanged(app, event)
            app.executeStateUpdate()
        end

        % Value changed function: AngularstepSpinner
        function AngularStepRadChanging(app, event)
            app.executeStateUpdate();
            
        end

        % Value changed function: DistancestepSpinner
        function PixelStep(app, event)
            app.executeStateUpdate();            
        end

        % Value changed function: HoughthresholdSpinner
        function HoughThresholdChanging(app, event)
            app.executeStateUpdate();            
        end

        % Value changed function: HoughmodeDropDown
        function HoughModeChanged(app, event)
            app.executeStateUpdate();
        end

        % Value changed function: BSpinner
        function BSpinnerChanged(app, event)
            r = app.RSpinner.Value;
            g = app.GSpinner.Value;
            b = app.BSpinner.Value;
            
            [r, g] = CalculateColorWeights(b, r, g);
            
            app.RSpinner.Value = r;
            app.GSpinner.Value = g;
            app.executeStateUpdate();
        end

        % Value changed function: RSpinner
        function RSpinnerChanged(app, event)
            r = app.RSpinner.Value;
            g = app.GSpinner.Value;
            b = app.BSpinner.Value;
            
            [g, b] = CalculateColorWeights(r, g, b);
            
            app.GSpinner.Value = g;
            app.BSpinner.Value = b;
            app.executeStateUpdate();
            
        end

        % Value changed function: GSpinner
        function GSpinnerChanged(app, event)
            r = app.RSpinner.Value;
            g = app.GSpinner.Value;
            b = app.BSpinner.Value;
            
            [r, b] = CalculateColorWeights(g, r, b);
            
            app.RSpinner.Value = r;
            app.BSpinner.Value = b;
            app.executeStateUpdate();
        end

        % Button pushed function: ResetplotButton
        function BRestetPlotPushed(app, event)
            app.FormatAxis(app.CurrentPlot, app.UIAxes);
        end

        % Value changed function: AnglularwindowSpinner
        function AWSpinnerChanged(app, event)
            app.executeStateUpdate();           
        end

        % Value changed function: DistancewindowSpinner
        function DWSpinnerChanged(app, event)
            app.executeStateUpdate();            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlidipUIFigure = uifigure('Visible', 'off');
            app.SlidipUIFigure.Name = 'Slidip';
            app.SlidipUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SlidipUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @BNextPushed, true);
            app.NextButton.Tooltip = {'Continue to the next step'};
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.GridLayout2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BBackPushed, true);
            app.BackButton.Tooltip = {'Go to previous step'};
            app.BackButton.Text = 'Back';
            app.SobelthresholdSpinnerLabel = uilabel(app.GridLayout2);
            app.SobelthresholdSpinnerLabel.Text = 'Sobel threshold';
            app.SobelthresholdSpinner = uispinner(app.GridLayout2);
            app.SobelthresholdSpinner.Step = 0.01;
            app.SobelthresholdSpinner.Limits = [0 1];
            app.SobelthresholdSpinner.ValueDisplayFormat = '%.2f';
            app.SobelthresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @SobelThresholdChanged, true);
            app.SobelthresholdSpinner.Tooltip = {'Controlles threshold percentage.'; 'Matrix entries larger than [value] times maxvalue will pass this filter'; ''};
            app.SobelthresholdSpinner.Value = 0.5;
            app.AngularstepSpinnerLabel = uilabel(app.GridLayout2);
            app.AngularstepSpinnerLabel.Text = 'Angular step';
            app.AngularstepSpinner = uispinner(app.GridLayout2);
            app.AngularstepSpinner.Step = 0.01;
            app.AngularstepSpinner.Limits = [0.01 1];
            app.AngularstepSpinner.ValueDisplayFormat = '%.2f';
            app.AngularstepSpinner.ValueChangedFcn = createCallbackFcn(app, @AngularStepRadChanging, true);
            app.AngularstepSpinner.Tooltip = {'Determines the stepwidth for the houghtransformation.'; 'Lower values encrease the numbers of calculations needed'};
            app.AngularstepSpinner.Value = 0.01;
            app.DistancestepSpinnerLabel = uilabel(app.GridLayout2);
            app.DistancestepSpinnerLabel.Text = {'Distance step'; ''};
            app.DistancestepSpinner = uispinner(app.GridLayout2);
            app.DistancestepSpinner.Limits = [0.1 20];
            app.DistancestepSpinner.ValueChangedFcn = createCallbackFcn(app, @PixelStep, true);
            app.DistancestepSpinner.Tooltip = {'Determines the stepwidth for the houghtransformation.'; 'Lower values encrease the numbers of calculations needed'};
            app.DistancestepSpinner.Value = 10;
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HoughthresholdSpinnerLabel = uilabel(app.GridLayout2);
            app.HoughthresholdSpinnerLabel.Text = 'Hough threshold';
            app.HoughthresholdSpinner = uispinner(app.GridLayout2);
            app.HoughthresholdSpinner.Step = 0.01;
            app.HoughthresholdSpinner.Limits = [0 1];
            app.HoughthresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @HoughThresholdChanging, true);
            app.HoughthresholdSpinner.Tooltip = {'Controlles threshold percentage.'; 'Matrix entries larger than [value] times maxvalue will pass this filter'};
            app.HoughthresholdSpinner.Value = 0.6;
            app.HoughmodeDropDownLabel = uilabel(app.GridLayout2);
            app.HoughmodeDropDownLabel.Text = 'Hough mode';
            app.HoughmodeDropDown = uidropdown(app.GridLayout2);
            app.HoughmodeDropDown.Items = {'For loop', 'Tensor mode'};
            app.HoughmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @HoughModeChanged, true);
            app.HoughmodeDropDown.Tooltip = {'Choose a mode.'; 'Different modes have different benefits'};
            app.HoughmodeDropDown.Value = 'For loop';
            app.ColorweightLabel = uilabel(app.GridLayout2);
            app.ColorweightLabel.Tooltip = {'Controlles the color weight for grayscale conversion.'; 'Default: Sensetivity levels of the human eye for each channel'};
            app.ColorweightLabel.Text = 'Color weight';
            app.RSpinner = uispinner(app.GridLayout2);
            app.RSpinner.Limits = [0 100];
            app.RSpinner.ValueDisplayFormat = '%.1f';
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerChanged, true);
            app.RSpinner.Tooltip = {'Red channel weight'};
            app.RSpinner.Value = 29.9;
            app.GSpinner = uispinner(app.GridLayout2);
            app.GSpinner.Limits = [0 100];
            app.GSpinner.ValueDisplayFormat = '%.1f';
            app.GSpinner.ValueChangedFcn = createCallbackFcn(app, @GSpinnerChanged, true);
            app.GSpinner.Tooltip = {'Green channel weight'};
            app.GSpinner.Value = 58.7;
            app.BSpinner = uispinner(app.GridLayout2);
            app.BSpinner.Limits = [0 100];
            app.BSpinner.ValueDisplayFormat = '%.1f';
            app.BSpinner.ValueChangedFcn = createCallbackFcn(app, @BSpinnerChanged, true);
            app.BSpinner.Tooltip = {'Blue channel weight'};
            app.BSpinner.Value = 11.4;
            app.ResetplotButton = uibutton(app.GridLayout2, 'push');
            app.ResetplotButton.ButtonPushedFcn = createCallbackFcn(app, @BRestetPlotPushed, true);
            app.ResetplotButton.Tooltip = {'Reset plot axis'};
            app.ResetplotButton.Text = 'Reset plot';
            app.AnglularwindowSpinnerLabel = uilabel(app.GridLayout2);
            app.AnglularwindowSpinnerLabel.Text = 'Anglular window';
            app.AnglularwindowSpinner = uispinner(app.GridLayout2);
            app.AnglularwindowSpinner.Limits = [-1 Inf];
            app.AnglularwindowSpinner.ValueChangedFcn = createCallbackFcn(app, @AWSpinnerChanged, true);
            app.AnglularwindowSpinner.Tooltip = {'Only lines with close neighbours are allowed.'; 'This field defines in which area lines are considered close;'; 'Set it negative in order to turn it off'};
            app.AnglularwindowSpinner.Value = -1;
            app.DistancewindowSpinnerLabel = uilabel(app.GridLayout2);
            app.DistancewindowSpinnerLabel.Text = 'Distance window';
            app.DistancewindowSpinner = uispinner(app.GridLayout2);
            app.DistancewindowSpinner.Limits = [-1 Inf];
            app.DistancewindowSpinner.ValueChangedFcn = createCallbackFcn(app, @DWSpinnerChanged, true);
            app.DistancewindowSpinner.Tooltip = {'Only lines with close neighbours are allowed.'; 'This field defines in which area lines are considered close;'; 'Set it negative in order to turn it off'};
            app.DistancewindowSpinner.Value = -1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.SlidipUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Slidip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlidipUIFigure)

            % Execute the startup function
            runStartupFcn(app, @AppStatup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlidipUIFigure)
        end
    end
end